classdef Fall2023AppV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        StartButton              matlab.ui.control.Button
        ExperimentStartLabel     matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        CalculateButton          matlab.ui.control.Button
        SaltCalculatorLabel      matlab.ui.control.Label
        ConcentrationMEditField  matlab.ui.control.NumericEditField
        ConcentrationMLabel      matlab.ui.control.Label
        VolumeLEditField         matlab.ui.control.NumericEditField
        VolumeLEditFieldLabel    matlab.ui.control.Label
        CenterPanel              matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        RightPanel               matlab.ui.container.Panel
        TextArea_3               matlab.ui.control.TextArea
        TextArea_2               matlab.ui.control.TextArea
        TimeElapsedLabel         matlab.ui.control.Label
        FluxRateLabel            matlab.ui.control.Label
        ValuesLabel              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        time_previous = 0; % Description
    end
    
    methods (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear var d, dq, DaqName, time_list, permeate_flowrate_list, batch_flowrate_list
            [d, dq, DaqName] = initalizeDaq();
            [time_list, permeate_flowrate_list, batch_flowrate_list, t] = initalizeData();
            background();


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            concentration = app.ConcentrationMEditField.Value;
            volume = app.VolumeLEditField.Value;
            [cacl,naso4] = CaSO4_mixing(concentration,volume);
            app.TextArea.Value = [num2str(cacl)+"g CaCl2", "",num2str(naso4)+"g NaSO4"];
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            main_run()
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.VolumeLEditFieldLabel = uilabel(app.LeftPanel);
            app.VolumeLEditFieldLabel.Text = 'Volume (L)';
            app.VolumeLEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ConcentrationMLabel = uilabel(app.LeftPanel);
            app.ConcentrationMLabel.Text = {'Concentration'; ' (M)'};
            app.ConcentrationMEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SaltCalculatorLabel = uilabel(app.LeftPanel);
            app.SaltCalculatorLabel.Text = 'Salt Calculator';
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.ExperimentStartLabel = uilabel(app.LeftPanel);
            app.ExperimentStartLabel.Text = 'Experiment Start';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Membrane Flux')
            xlabel(app.UIAxes, 'time (hours)')
            ylabel(app.UIAxes, 'Flow Rate')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.ValuesLabel = uilabel(app.RightPanel);
            app.ValuesLabel.Text = 'Values';
            app.FluxRateLabel = uilabel(app.RightPanel);
            app.FluxRateLabel.Text = 'Flux Rate';
            app.TimeElapsedLabel = uilabel(app.RightPanel);
            app.TimeElapsedLabel.Text = 'Time Elapsed';
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_3 = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fall2023AppV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end